classdef AeroFlow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        BasicconfigurationsMenu       matlab.ui.container.Menu
        HalfRankineOvalMenu           matlab.ui.container.Menu
        RankineOvalMenu               matlab.ui.container.Menu
        NonliftingflowoveracylinderMenu  matlab.ui.container.Menu
        LiftingflowoveracylinderMenu  matlab.ui.container.Menu
        BathtubevortexMenu            matlab.ui.container.Menu
        GroundeffectMenu              matlab.ui.container.Menu
        FiniterowofvorticesMenu       matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        JukowskitransformCheckBox     matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        IntensitySliderLabel          matlab.ui.control.Label
        IntensitySlider               matlab.ui.control.Slider
        VortexnumberSpinner           matlab.ui.control.Spinner
        VortexnumberSpinnerLabel      matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        SeparationEditField           matlab.ui.control.NumericEditField
        SeparationEditFieldLabel      matlab.ui.control.Label
        AcceptButton                  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        AddnewflowButton              matlab.ui.control.Button
        DeleteflowButton              matlab.ui.control.Button
        StreamplotCheckBox            matlab.ui.control.CheckBox
        StreamlinesCheckBox           matlab.ui.control.CheckBox
        AERODYNAMICFLOWANALYSISLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function addFlow(app)
            ft = {'Uniform', 'Source', 'Sink', 'Doublet', 'Vortex'};
            newFlow(1,1).FlowType = ft(randi([1 5]));
            newFlow(1,1).X = randi([-4 4]);
            newFlow(1,1).Y = randi([-2 2]);
            newFlow(1,1).Intensity = randi([1 10]);
            newFlow(1,1).Active = true;
            app.UITable.Data = [app.UITable.Data; struct2table(newFlow)];
        end
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            [x,y] = meshgrid(-5:0.01:5,-2.5:0.01:2.5);
            [x2,y2] = meshgrid(-5:0.05:5,-2.5:0.04:2.5);
            data = app.UITable.Data;
            if app.JukowskitransformCheckBox.Value
                [X,Y]=JukowskiTransform(app,x,y);
                [X2,Y2]=JukowskiTransform(app,x2,y2);
            else
                X = x;
                Y = y;
                X2 = x2;
                Y2 = y2;
            end
            Z = zeros(height(X), width(X));
            U = zeros(height(X2), width(X2));
            V = zeros(height(X2), width(X2));
            for c = 1:height(data)
                type = cellstr(data.FlowType(c));
                posX = data.X(c);
                posY = data.Y(c);
                Intensity = data.Intensity(c);
                switch type{:}
                    case 'Uniform'
                        app.UITable.Data.X(c) = nan;
                        app.UITable.Data.Y(c) = nan;
                        z = UniformFlow(app, X, Y, Intensity);
                        [u, v] = UniformFlowVel(app, X2, Y2, Intensity);
                    case 'Source'
                        z = SourceFlow(app, X, Y, posX, posY, Intensity);
                        [u, v] = SourceFlowVel(app, X2, Y2, posX, posY, Intensity);
                    case 'Sink'
                        z = SinkFlow(app, X, Y, posX, posY, Intensity);
                        [u, v] = SinkFlowVel(app, X2, Y2, posX, posY, Intensity);
                    case 'Doublet'
                        z = DoubletFlow(app, X, Y, posX, posY, Intensity);
                        [u, v] = DoubletFlowVel(app, X2, Y2, posX, posY, Intensity);
                    case 'Vortex'
                        z = VortexFlow(app, X, Y, posX, posY, Intensity);
                        [u, v] = VortexFlowVel(app, X2, Y2, posX, posY, Intensity);
                end
                if data.Active(c) == true
                    Z = Z + z;
                    U = U + u;
                    V = V + v;
                end
            end
            vel = sqrt(U.^2+V.^2);
            U = U./vel;
            V = V./vel;
            hold(app.UIAxes,'off')
            if app.StreamlinesCheckBox.Value
                contour(app.UIAxes, x, y, Z, linspace(-50,50,51), 'k');
                hold(app.UIAxes,'on')
            end
            if app.StreamplotCheckBox.Value
                skp = 10;
                quiver(app.UIAxes, x2(1:skp:end, 1:skp:end), ...
                    y2(1:skp:end, 1:skp:end), U(1:skp:end, 1:skp:end), ...
                    V(1:skp:end, 1:skp:end), 0.5);
            end
        end
        
        function z = UniformFlow(~,~,y,intensity)
            z = intensity*y;
        end
        function [u,v] = UniformFlowVel(~,~,~,intensity)
            u = intensity;
            v = 0;
        end
        
        function z = SourceFlow(~,x,y,posX,posY,intensity)
            z = intensity*atan2(y-posY, x-posX)/(2*pi);
        end
        function [u,v] = SourceFlowVel(~,x,y,posX,posY,intensity)
            u = intensity/(2*pi)*(x-posX)./((x-posX).^2+(y-posY).^2);
            v = intensity/(2*pi)*(y-posY)./((x-posX).^2+(y-posY).^2);
        end
        
        function z = SinkFlow(~,x,y,posX,posY,intensity)
            z = -intensity*atan2(y-posY, x-posX)/(2*pi);
        end
        function [u,v] = SinkFlowVel(~,x,y,posX,posY,intensity)
            u = -intensity/(2*pi)*(x-posX)./((x-posX).^2+(y-posY).^2);
            v = -intensity/(2*pi)*(y-posY)./((x-posX).^2+(y-posY).^2);
        end
        
        function z = DoubletFlow(~,x,y,posX,posY,intensity)
            z = -intensity*(y-posY)./(2*pi*((x-posX).^2+(y-posY).^2));
        end
        function [u,v] = DoubletFlowVel(~,x,y,posX,posY,intensity)
            u = -intensity*((x-posX).^2-(y-posY).^2)./(2*pi*((x-posX).^2+(y-posY).^2).^2);
            v = -intensity*((x-posX).*(y-posY))./(2*pi*((x-posX).^2+(y-posY).^2).^2);
        end
        function z = VortexFlow(~,x,y,posX,posY,intensity)
            z = intensity/(4*pi)*log((x-posX).^2+(y-posY).^2);
        end
        function [u,v] = VortexFlowVel(~,x,y,posX,posY,intensity)
            u = intensity*(y-posY)./(2*pi*((x-posX).^2+(y-posY).^2));
            v = -intensity*(x-posX)./(2*pi*((x-posX).^2+(y-posY).^2));
        end
        
        
        function [xi, yi] = JukowskiTransform(~,x, y)
            z = x+1i.*y;
            epsilon = z;
            xi = real(epsilon);
            yi = imag(epsilon);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = table;
            t.FlowType = categorical({'Uniform'},{'Uniform', 'Source', ...
                'Sink', 'Doublet', 'Vortex'});
            t.X = nan;
            t.Y = nan;
            t.Intensity = 10;
            t.Active = true;
            app.UITable.Data = t;
            app.UITable.ColumnEditable = true;
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-2.5 2.5];
            app.StreamlinesCheckBox.Value = true;
            updatePlot(app);
        end

        % Button pushed function: AddnewflowButton
        function AddnewflowButtonPushed(app, event)
            addFlow(app);
            updatePlot(app);
        end

        % Button pushed function: DeleteflowButton
        function DeleteflowButtonPushed(app, event)
            
        end

        % Value changed function: StreamplotCheckBox
        function StreamplotCheckBoxValueChanged(app, event)
           updatePlot(app);
        end

        % Value changed function: StreamlinesCheckBox
        function StreamlinesCheckBoxValueChanged(app, event)
            updatePlot(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            updatePlot(app);
        end

        % Menu selected function: HalfRankineOvalMenu
        function HalfRankineOvalMenuSelected(app, event)
            t = table;
            t.FlowType = categorical({'Uniform'; 'Source'},{'Uniform', ...
                'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = [nan; -1];
            t.Y = [nan; 0];
            t.Intensity = [6; 20];
            t.Active = [true; true];
            app.UITable.Data = t;
            updatePlot(app);
        end

        % Menu selected function: RankineOvalMenu
        function RankineOvalMenuSelected(app, event)
            t = table;
            t.FlowType = categorical({'Uniform'; 'Source'; 'Sink'}, ...
                {'Uniform', 'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = [nan; -1; 1];
            t.Y = [nan; 0; 0];
            t.Intensity = [6; 20; 20];
            t.Active = [true; true; true];
            app.UITable.Data = t;
            updatePlot(app);
        end

        % Menu selected function: NonliftingflowoveracylinderMenu
        function NonliftingflowoveracylinderMenuSelected(app, event)
            t = table;
            t.FlowType = categorical({'Uniform'; 'Doublet'},{'Uniform', ...
                'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = [nan; 0];
            t.Y = [nan; 0];
            t.Intensity = [6; 20];
            t.Active = [true; true];
            app.UITable.Data = t;
            updatePlot(app);
            set(0,'DefaultFigureVisible','off');
            R = sqrt(20/(2*pi*6));
            if app.JukowskitransformCheckBox.Value
                
            else
                plot(app.UIAxes, nsidedpoly(1000, 'Center', [0 0], ...
                    'Radius', R), 'FaceColor', 'r')
                hold off
                alpha(.5)
            end
        end

        % Callback function
        function LinevectordensitySliderValueChanged(app, event)
            updatePlot(app);
        end

        % Menu selected function: BathtubevortexMenu
        function BathtubevortexMenuSelected(app, event)
            t = table;
            t.FlowType = categorical({'Vortex'; 'Sink'},{'Uniform', ...
                'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = [0; 0];
            t.Y = [0; 0];
            t.Intensity = [30; 30];
            t.Active = [true; true];
            app.UITable.Data = t;
            updatePlot(app);
        end

        % Menu selected function: LiftingflowoveracylinderMenu
        function LiftingflowoveracylinderMenuSelected(app, event)
            t = table;
            t.FlowType = categorical({'Uniform'; 'Doublet'; 'Vortex'}, ...
                {'Uniform', 'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = [nan; 0; 0];
            t.Y = [nan; 0; 0];
            t.Intensity = [6; 20; 30];
            t.Active = [true; true; true];
            app.UITable.Data = t;
            updatePlot(app);
            set(0,'DefaultFigureVisible','off');
            R = sqrt(20/(2*pi*6));
            plot(app.UIAxes, nsidedpoly(1000, 'Center', [0 0], ...
                'Radius', R), 'FaceColor', 'r')
            hold off
            alpha(.5)
        end

        % Menu selected function: GroundeffectMenu
        function GroundeffectMenuSelected(app, event)
            t = table;
            t.FlowType = categorical({'Uniform'; 'Vortex'; 'Vortex'}, ...
                {'Uniform', 'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = [nan; 0; 0];
            t.Y = [nan; 1; -1];
            t.Intensity = [6; 40; -40];
            t.Active = [true; true; true];
            app.UITable.Data = t;
            updatePlot(app);
            X = [-5 5];
            Y = [0 0];
            plot(app.UIAxes, X, Y, "LineWidth", 2,"Color",'#804000');
        end

        % Menu selected function: FiniterowofvorticesMenu
        function FiniterowofvorticesMenuSelected(app, event)
            app.Panel.Visible = 'on';
        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.Panel.Visible = 'off';
            t = table;
            ft = {};
            num = app.VortexnumberSpinner.Value;
            sep = app.SeparationEditField.Value;
            width = (num-1)*sep/2;
            for c = 1:num
                ft{end+1}='Vortex';
            end
            t.FlowType = categorical(transpose(ft), ...
                {'Uniform', 'Source', 'Sink', 'Doublet', 'Vortex'});
            t.X = transpose(linspace(-width,width,num));
            t.Y = zeros(num,1);
            t.Intensity = 25*app.IntensitySlider.Value/num*ones(num,1);
            t.Active = true(num,1);
            app.UITable.Data = t;
            updatePlot(app);
            set(0,'DefaultFigureVisible','off');
            X = [-width width];
            Y = [0 0];
            plot(app.UIAxes, X, Y, "LineWidth", 2,"Color",'r');
            alpha(.5)
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Panel.Visible = 'off';
        end

        % Value changed function: JukowskitransformCheckBox
        function JukowskitransformCheckBoxValueChanged(app, event)
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu      ';
            app.BasicconfigurationsMenu = uimenu(app.UIFigure);
            app.BasicconfigurationsMenu.Text = 'Basic configurations     ';
            app.HalfRankineOvalMenu = uimenu(app.BasicconfigurationsMenu);
            app.HalfRankineOvalMenu.MenuSelectedFcn = createCallbackFcn(app, @HalfRankineOvalMenuSelected, true);
            app.HalfRankineOvalMenu.Text = 'Half Rankine Oval';
            app.RankineOvalMenu = uimenu(app.BasicconfigurationsMenu);
            app.RankineOvalMenu.MenuSelectedFcn = createCallbackFcn(app, @RankineOvalMenuSelected, true);
            app.RankineOvalMenu.Text = 'Rankine Oval';
            app.NonliftingflowoveracylinderMenu = uimenu(app.BasicconfigurationsMenu);
            app.NonliftingflowoveracylinderMenu.MenuSelectedFcn = createCallbackFcn(app, @NonliftingflowoveracylinderMenuSelected, true);
            app.NonliftingflowoveracylinderMenu.Text = 'Non-lifting flow over a cylinder';
            app.LiftingflowoveracylinderMenu = uimenu(app.BasicconfigurationsMenu);
            app.LiftingflowoveracylinderMenu.MenuSelectedFcn = createCallbackFcn(app, @LiftingflowoveracylinderMenuSelected, true);
            app.LiftingflowoveracylinderMenu.Text = 'Lifting flow over a cylinder';
            app.BathtubevortexMenu = uimenu(app.BasicconfigurationsMenu);
            app.BathtubevortexMenu.MenuSelectedFcn = createCallbackFcn(app, @BathtubevortexMenuSelected, true);
            app.BathtubevortexMenu.Text = 'Bath-tube vortex';
            app.GroundeffectMenu = uimenu(app.BasicconfigurationsMenu);
            app.GroundeffectMenu.MenuSelectedFcn = createCallbackFcn(app, @GroundeffectMenuSelected, true);
            app.GroundeffectMenu.Text = 'Ground effect';
            app.FiniterowofvorticesMenu = uimenu(app.BasicconfigurationsMenu);
            app.FiniterowofvorticesMenu.MenuSelectedFcn = createCallbackFcn(app, @FiniterowofvorticesMenuSelected, true);
            app.FiniterowofvorticesMenu.Text = 'Finite row of vortices';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.XTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.YTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'};
            app.AERODYNAMICFLOWANALYSISLabel = uilabel(app.GridLayout);
            app.AERODYNAMICFLOWANALYSISLabel.Text = 'AERODYNAMIC FLOW ANALYSIS';
            app.StreamlinesCheckBox = uicheckbox(app.GridLayout);
            app.StreamlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @StreamlinesCheckBoxValueChanged, true);
            app.StreamlinesCheckBox.Text = 'Streamlines';
            app.StreamplotCheckBox = uicheckbox(app.GridLayout);
            app.StreamplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @StreamplotCheckBoxValueChanged, true);
            app.StreamplotCheckBox.Text = 'Stream plot';
            app.DeleteflowButton = uibutton(app.GridLayout, 'push');
            app.DeleteflowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteflowButtonPushed, true);
            app.DeleteflowButton.Text = 'Delete flow';
            app.AddnewflowButton = uibutton(app.GridLayout, 'push');
            app.AddnewflowButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewflowButtonPushed, true);
            app.AddnewflowButton.Text = 'Add new flow';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Flow Type'; 'X'; 'Y'; 'Intensity'; 'Active'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.Panel.Visible = 'off';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.AcceptButton = uibutton(app.GridLayout2, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.SeparationEditFieldLabel = uilabel(app.GridLayout2);
            app.SeparationEditFieldLabel.Text = 'Separation';
            app.SeparationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SeparationEditField.Limits = [0 1];
            app.SeparationEditField.Value = 0.01;
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.VortexnumberSpinnerLabel = uilabel(app.GridLayout2);
            app.VortexnumberSpinnerLabel.Text = 'Vortex number';
            app.VortexnumberSpinner = uispinner(app.GridLayout2);
            app.VortexnumberSpinner.Limits = [1 500];
            app.VortexnumberSpinner.Value = 200;
            app.IntensitySlider = uislider(app.GridLayout2);
            app.IntensitySlider.Limits = [1 8];
            app.IntensitySlider.MajorTicks = [1 8];
            app.IntensitySlider.MajorTickLabels = {'Min ', 'Max'};
            app.IntensitySlider.Value = 1;
            app.IntensitySliderLabel = uilabel(app.GridLayout2);
            app.IntensitySliderLabel.Text = 'Intensity';
            app.JukowskitransformCheckBox = uicheckbox(app.GridLayout);
            app.JukowskitransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @JukowskitransformCheckBoxValueChanged, true);
            app.JukowskitransformCheckBox.Text = 'Jukowski transform';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AeroFlow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end